classdef MatLabCalc < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        AEditFieldLabel    matlab.ui.control.Label
        InputA             matlab.ui.control.NumericEditField
        BEditFieldLabel    matlab.ui.control.Label
        InputB             matlab.ui.control.NumericEditField
        Subtract           matlab.ui.control.Button
        ResultField        matlab.ui.control.NumericEditField
        Divide             matlab.ui.control.Button
        SqrtAButton        matlab.ui.control.Button
        SqrtBButton        matlab.ui.control.Button
        ForceA2Button      matlab.ui.control.Button
        Multiply           matlab.ui.control.Button
        Sum                matlab.ui.control.Button
        ClearAButton       matlab.ui.control.Button
        ClearBButton       matlab.ui.control.Button
        ForceB2Button      matlab.ui.control.Button
        AforceB            matlab.ui.control.Button
        BforceA            matlab.ui.control.Button
        FactorialAButton   matlab.ui.control.Button
        FactorialB         matlab.ui.control.Button
        TanBButton         matlab.ui.control.Button
        CotBButton         matlab.ui.control.Button
        CosBButton         matlab.ui.control.Button
        SinBButton         matlab.ui.control.Button
        SinAButton         matlab.ui.control.Button
        CosAButton         matlab.ui.control.Button
        TanAButton         matlab.ui.control.Button
        CotAButton         matlab.ui.control.Button
        ClearScreenButton  matlab.ui.control.Button
        IncrementA         matlab.ui.control.Button
        DecrementA         matlab.ui.control.Button
        IncrementB         matlab.ui.control.Button
        DecrementB         matlab.ui.control.Button
        OnOffSwitch        matlab.ui.control.Switch
    end

    properties (Access = private)
        PropertyA = 0; % Globalne zmiennie przechowujące liczby 
        PropertyB = 0;  
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ClearAButton
        function ClearAButtonPushed(app, event)
            %czyszczenie wartości pola liczby A
            app.InputA.Value = 0; 
        end

        % Button pushed function: ClearBButton
        function ClearBButtonPushed(app, event)
               %czyszczenie wartości pola liczby B
            app.InputB.Value = 0;
        end

        % Value changed function: InputA
        function InputAValueChanged(app, event)
            %przypisywanie wartości liczbowej polu A
            value = app.InputA.Value;
            app.PropertyA = value;
        end

        % Value changed function: InputB
        function InputBValueChanged(app, event)
            %przypisywanie wartości liczbowej polu A
            value = app.InputB.Value;
            app.PropertyB = value;
        end

        % Button pushed function: Subtract
        function SubtractButtonPushed(app, event)
            %elementarna operacja odejmowania 
            app.ResultField.Value = app.PropertyA - app.PropertyB;
            disp(app.ResultField.Value);
        end

        % Button pushed function: ClearScreenButton
        function ClearScreenButtonPushed(app, event)
            %Czyszczenie wartości pola wynikowego
            app.ResultField.Value = 0;
        end

        % Button pushed function: Multiply
        function MultiplyButtonPushed(app, event)
            %elementarna operacja mnożenia
            app.ResultField.Value = app.PropertyA * app.PropertyB;
            disp(app.ResultField.Value);
        end

        % Button pushed function: Divide
        function DivideButtonPushed(app, event)
            %elementarna operacja dzielenia
            app.ResultField.Value = app.PropertyA / app.PropertyB;
            disp(app.ResultField.Value);
        end

        % Value changed function: OnOffSwitch
        function OnOffSwitchValueChanged(app, event)
            %Ukrywanie widoczności okienka aplikacji za pomocą przycisku,
            %widoczność okna aplikacji zależna jest od wartości pola
            %Visible komponentu
            value = app.OnOffSwitch.Value;
            if strcmp(value , "On")
                app.UIFigure.Visible = "on";
            else 
                app.UIFigure.Visible = "off";
                
            end
        end

        % Button pushed function: ForceA2Button
        function ForceA2ButtonPushed(app, event)
            %druga potęga liczbyA
            app.ResultField.Value = app.PropertyA ^ 2;
            disp(app.ResultField.Value);
        end

        % Button pushed function: ForceB2Button
        function ForceB2ButtonPushed(app, event)
            %druga potęga liczby B
            app.ResultField.Value = app.PropertyB ^ 2;
            disp(app.ResultField.Value); 
        end

        % Button pushed function: AforceB
        function AforceBButtonPushed(app, event)
            %B-eta potęga liczby A
            app.ResultField.Value = app.PropertyA ^ app.PropertyB;
            disp(app.ResultField.Value); 
        end

        % Button pushed function: BforceA
        function BforceAButtonPushed(app, event)
            %A-ta potęga liczby B
            app.ResultField.Value = app.PropertyB ^ app.PropertyA;
            disp(app.ResultField.Value); 
        end

        % Button pushed function: IncrementA
        function IncrementAButtonPushed(app, event)
            %Inkrementacja wartości pola A
            app.PropertyA = 1 + app.PropertyA;
            app.InputA.Value = app.PropertyA;
        end

        % Button pushed function: IncrementB
        function IncrementBButtonPushed(app, event)
            %Inkrementacja wartości pola B
            app.PropertyB = 1 + app.PropertyB;
            app.InputB.Value = app.PropertyB;
        end

        % Button pushed function: DecrementA
        function DecrementAButtonPushed(app, event)
            %Dekrementacja wartości pola A
            app.PropertyA = app.PropertyA - 1;
            app.InputA.Value = app.PropertyA;
        end

        % Button pushed function: DecrementB
        function DecrementBButtonPushed(app, event)
            %Dekrementacja wartości pola B
            app.PropertyB = app.PropertyB - 1;
            app.InputB.Value = app.PropertyB;
        end

        % Button pushed function: SqrtAButton
        function SqrtAButtonPushed(app, event)
            %Pierwiastek kwadratowy z liczby A
            app.ResultField.Value = sqrt(app.PropertyA);
            disp(app.ResultField.Value);
        end

        % Button pushed function: SqrtBButton
        function SqrtBButtonPushed(app, event)
            %Pierwiastek kwadratowy z liczby B
            app.ResultField.Value = sqrt(app.PropertyB);
            disp(app.ResultField.Value);
        end

        % Button pushed function: FactorialAButton
        function FactorialAButtonPushed(app, event)
            %silnia z liczby A
            app.ResultField.Value = factorial(app.PropertyA);
            disp(app.ResultField.Value);
        end

        % Button pushed function: FactorialB
        function FactorialBButtonPushed(app, event)
            %Silnia z liczby B 
            app.ResultField.Value = factorial(app.PropertyB);
            disp(app.ResultField.Value);
        end

        % Button pushed function: TanBButton
        function TanBButtonPushed(app, event)
            %Obliczenie wartości tangensa z liczby B
            app.ResultField.Value = tan(app.PropertyB);
            disp(app.ResultField.Value);
        end

        % Button pushed function: TanAButton
        function TanAButtonPushed(app, event)
            %Obliczenie wartości tangensa z liczby A
            app.ResultField.Value = tan(app.PropertyA);
            disp(app.ResultField.Value);
        end

        % Button pushed function: CotBButton
        function CotBButtonPushed(app, event)
            %Obliczenie wartości cotangensa z liczby B
            app.ResultField.Value = cot(app.PropertyB);
            disp(app.ResultField.Value);
        end

        % Button pushed function: CotAButton
        function CotAButtonPushed(app, event)
            %Obliczenie wartości cotangensa z liczby A
            app.ResultField.Value = cot(app.PropertyA);
            disp(app.ResultField.Value);
        end

        % Button pushed function: SinBButton
        function SinBButtonPushed(app, event)
            %Obliczenie wartości sinusa z liczby B
            app.ResultField.Value = sin(app.PropertyB);
            disp(app.ResultField.Value);
        end

        % Button pushed function: SinAButton
        function SinAButtonPushed(app, event)
            %Obliczenie wartości sinusa z liczby A
            app.ResultField.Value = sin(app.PropertyA);
            disp(app.ResultField.Value);
        end

        % Button pushed function: CosBButton
        function CosBButtonPushed(app, event)
            %Obliczenie wartości cosinusa z liczby B
            app.ResultField.Value = cos(app.PropertyB);
            disp(app.ResultField.Value);
        end

        % Button pushed function: CosAButton
        function CosAButtonPushed(app, event)
            %Obliczenie wartości cosinusa z liczby A
            app.ResultField.Value = cos(app.PropertyA);
            disp(app.ResultField.Value);
        end

        % Button pushed function: Sum
        function SumButtonPushed(app, event)
            %elementarna operacja dodawania 
            app.ResultField.Value = app.PropertyA + app.PropertyB;
            disp(app.ResultField.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AEditFieldLabel = uilabel(app.UIFigure);
            app.AEditFieldLabel.Text = 'A';
            app.InputA = uieditfield(app.UIFigure, 'numeric');
            app.InputA.ValueChangedFcn = createCallbackFcn(app, @InputAValueChanged, true);
            app.BEditFieldLabel = uilabel(app.UIFigure);
            app.BEditFieldLabel.Text = 'B';
            app.InputB = uieditfield(app.UIFigure, 'numeric');
            app.InputB.ValueChangedFcn = createCallbackFcn(app, @InputBValueChanged, true);
            app.Subtract = uibutton(app.UIFigure, 'push');
            app.Subtract.ButtonPushedFcn = createCallbackFcn(app, @SubtractButtonPushed, true);
            app.Subtract.Text = '-';
            app.ResultField = uieditfield(app.UIFigure, 'numeric');
            app.Divide = uibutton(app.UIFigure, 'push');
            app.Divide.ButtonPushedFcn = createCallbackFcn(app, @DivideButtonPushed, true);
            app.Divide.Text = '÷';
            app.SqrtAButton = uibutton(app.UIFigure, 'push');
            app.SqrtAButton.ButtonPushedFcn = createCallbackFcn(app, @SqrtAButtonPushed, true);
            app.SqrtAButton.Text = 'SqrtA';
            app.SqrtBButton = uibutton(app.UIFigure, 'push');
            app.SqrtBButton.ButtonPushedFcn = createCallbackFcn(app, @SqrtBButtonPushed, true);
            app.SqrtBButton.Text = 'SqrtB';
            app.ForceA2Button = uibutton(app.UIFigure, 'push');
            app.ForceA2Button.ButtonPushedFcn = createCallbackFcn(app, @ForceA2ButtonPushed, true);
            app.ForceA2Button.Text = 'a^2';
            app.Multiply = uibutton(app.UIFigure, 'push');
            app.Multiply.ButtonPushedFcn = createCallbackFcn(app, @MultiplyButtonPushed, true);
            app.Multiply.Text = 'x';
            app.Sum = uibutton(app.UIFigure, 'push');
            app.Sum.ButtonPushedFcn = createCallbackFcn(app, @SumButtonPushed, true);
            app.Sum.Text = '+';
            app.ClearAButton = uibutton(app.UIFigure, 'push');
            app.ClearAButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAButtonPushed, true);
            app.ClearAButton.Text = 'ClearA';
            app.ClearBButton = uibutton(app.UIFigure, 'push');
            app.ClearBButton.ButtonPushedFcn = createCallbackFcn(app, @ClearBButtonPushed, true);
            app.ClearBButton.Text = 'ClearB';
            app.ForceB2Button = uibutton(app.UIFigure, 'push');
            app.ForceB2Button.ButtonPushedFcn = createCallbackFcn(app, @ForceB2ButtonPushed, true);
            app.ForceB2Button.Text = 'b^2';
            app.AforceB = uibutton(app.UIFigure, 'push');
            app.AforceB.ButtonPushedFcn = createCallbackFcn(app, @AforceBButtonPushed, true);
            app.AforceB.Text = 'a^b';
            app.BforceA = uibutton(app.UIFigure, 'push');
            app.BforceA.ButtonPushedFcn = createCallbackFcn(app, @BforceAButtonPushed, true);
            app.BforceA.Text = 'b^a';
            app.FactorialAButton = uibutton(app.UIFigure, 'push');
            app.FactorialAButton.ButtonPushedFcn = createCallbackFcn(app, @FactorialAButtonPushed, true);
            app.FactorialAButton.Text = 'A!';
            app.FactorialB = uibutton(app.UIFigure, 'push');
            app.FactorialB.ButtonPushedFcn = createCallbackFcn(app, @FactorialBButtonPushed, true);
            app.FactorialB.Text = 'B!';
            app.TanBButton = uibutton(app.UIFigure, 'push');
            app.TanBButton.ButtonPushedFcn = createCallbackFcn(app, @TanBButtonPushed, true);
            app.TanBButton.Text = 'TanB';
            app.CotBButton = uibutton(app.UIFigure, 'push');
            app.CotBButton.ButtonPushedFcn = createCallbackFcn(app, @CotBButtonPushed, true);
            app.CotBButton.Text = 'CotB';
            app.CosBButton = uibutton(app.UIFigure, 'push');
            app.CosBButton.ButtonPushedFcn = createCallbackFcn(app, @CosBButtonPushed, true);
            app.CosBButton.Text = 'CosB';
            app.SinBButton = uibutton(app.UIFigure, 'push');
            app.SinBButton.ButtonPushedFcn = createCallbackFcn(app, @SinBButtonPushed, true);
            app.SinBButton.Text = 'SinB';
            app.SinAButton = uibutton(app.UIFigure, 'push');
            app.SinAButton.ButtonPushedFcn = createCallbackFcn(app, @SinAButtonPushed, true);
            app.SinAButton.Text = 'SinA';
            app.CosAButton = uibutton(app.UIFigure, 'push');
            app.CosAButton.ButtonPushedFcn = createCallbackFcn(app, @CosAButtonPushed, true);
            app.CosAButton.Text = 'CosA';
            app.TanAButton = uibutton(app.UIFigure, 'push');
            app.TanAButton.ButtonPushedFcn = createCallbackFcn(app, @TanAButtonPushed, true);
            app.TanAButton.Text = 'TanA';
            app.CotAButton = uibutton(app.UIFigure, 'push');
            app.CotAButton.ButtonPushedFcn = createCallbackFcn(app, @CotAButtonPushed, true);
            app.CotAButton.Text = 'CotA';
            app.ClearScreenButton = uibutton(app.UIFigure, 'push');
            app.ClearScreenButton.ButtonPushedFcn = createCallbackFcn(app, @ClearScreenButtonPushed, true);
            app.ClearScreenButton.Text = 'Clear Screen';
            app.IncrementA = uibutton(app.UIFigure, 'push');
            app.IncrementA.ButtonPushedFcn = createCallbackFcn(app, @IncrementAButtonPushed, true);
            app.IncrementA.Text = '+';
            app.DecrementA = uibutton(app.UIFigure, 'push');
            app.DecrementA.ButtonPushedFcn = createCallbackFcn(app, @DecrementAButtonPushed, true);
            app.DecrementA.Text = '-';
            app.IncrementB = uibutton(app.UIFigure, 'push');
            app.IncrementB.ButtonPushedFcn = createCallbackFcn(app, @IncrementBButtonPushed, true);
            app.IncrementB.Text = '+';
            app.DecrementB = uibutton(app.UIFigure, 'push');
            app.DecrementB.ButtonPushedFcn = createCallbackFcn(app, @DecrementBButtonPushed, true);
            app.DecrementB.Text = '-';
            app.OnOffSwitch = uiswitch(app.UIFigure, 'slider');
            app.OnOffSwitch.Items = {'On', 'Off'};
            app.OnOffSwitch.ValueChangedFcn = createCallbackFcn(app, @OnOffSwitchValueChanged, true);
            app.OnOffSwitch.Value = 'On';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MatLabCalc

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end